classdef CHAT_surface_finder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AutodetectaroundselectionButton  matlab.ui.control.Button
        PeakshiftEditField              matlab.ui.control.NumericEditField
        PeakshiftinadjacentboxesEditFieldLabel  matlab.ui.control.Label
        AcceptpeaksButton               matlab.ui.control.Button
        ReselectButton                  matlab.ui.control.Button
        CurrentselectionLabel           matlab.ui.control.Label
        TogglesurfacesButton            matlab.ui.control.StateButton
        Right_Button                    matlab.ui.control.Button
        Left_Button                     matlab.ui.control.Button
        Down_Button                     matlab.ui.control.Button
        Up_Button                       matlab.ui.control.Button
        SkippedinterpolatedLabel        matlab.ui.control.Label
        AutoselectedLabel               matlab.ui.control.Label
        ManuallyselectedLabel           matlab.ui.control.Label
        SkipboxButton                   matlab.ui.control.Button
        SelectpeaksmanuallyButton       matlab.ui.control.Button
        ComputesurfacesButton           matlab.ui.control.Button
        SelectboxButton                 matlab.ui.control.Button
        LoadsurfacesButton              matlab.ui.control.Button
        SavesurfacesButton              matlab.ui.control.Button
        ZresEditField                   matlab.ui.control.NumericEditField
        ZresmframeEditFieldLabel        matlab.ui.control.Label
        XYresEditField                  matlab.ui.control.NumericEditField
        XYresmpixEditFieldLabel         matlab.ui.control.Label
        LoadimagesfromfolderButton      matlab.ui.control.Button
        TopdownviewButton               matlab.ui.control.Button
        SmoothingwindowframesEditField  matlab.ui.control.NumericEditField
        SmoothingwindowframesEditFieldLabel  matlab.ui.control.Label
        ColumnsEditField                matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel           matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        RowsEditFieldLabel              matlab.ui.control.Label
        surfAxes                        matlab.ui.control.UIAxes
        projAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        chat_im % image stack of chat data
        w % width of image
        h % height of image
        xy_res % xy resolution (microns per pixel)
        z_res = 0.5; % z resolution (microns per frame)
        dend_proj_im % maximum projection image of dendrites
        Nframes % number of frames in image stack
        surf_frame_lower % surface quantized by the box resolution in frames
        surf_frame_upper % surface quantized by the box resolution in frames
        surf_upper % full smoothed surface (pixels)
        surf_lower %full smoothed surface (pixels)
        edges_rows % Description
        edges_cols % Description
        centers_rows % Description
        centers_cols % Description
        rect % rectangle objects matrix (rows x cols of the boxes)
        surf_lower_obj % graphics handle
        surf_upper_obj % graphics handle
        selection_mode = false; % bool for currently selecting a box
        selected_row = 0;
        selected_col = 0;
        f_trace % current fluorescence trace
        roi_1 % selected peak in trace
        roi_2 % selected peak in trace
        selection_type % cell array (rows x cols of the boxes) storing the type of selection (manual, auto, skipped)
        box_patches %
        surface_generated = false % boolean to track whether any surface has been generated
    end
    
    methods (Access = private)
        
        function load_folder(app, fname)
            if nargin < 2
                fname = [];
            end
            if isempty(fname)
                fpath = uigetdir('','Select folder with images');
            else
                fpath = fname;
            end
            set(app.UIFigure,'Name','Loading images...');

            try
                C = strsplit(fpath,filesep);
                cellname = C{end};
                fname_chat = sprintf('%s%s%s_chat.tif', fpath, filesep, cellname);
                fname_cell = sprintf('%s%s%s_cell.tif', fpath, filesep, cellname);

                info = imfinfo(fname_chat);
                app.Nframes = length(info);
                app.w = info(1).Width;
                app.h = info(1).Height;
                app.xy_res = 1./info(1).XResolution;
                app.XYresEditField.Value = app.xy_res;           
                desc = info(1).ImageDescription;
                try
                   a = strsplit(desc,'spacing=');
                   app.z_res = str2double(strtok(a{2}));
                   app.ZresEditField.Value = app.z_res;                
                catch
                   disp('z spacing not found in file');
                end
                
                app.chat_im = int32(zeros(app.h, app.w, app.Nframes));

                for i=1:app.Nframes
                    app.chat_im(:,:,i) = imread(fname_chat, i);
                end

%                 %use current boxes number to divide image and set enable to false
%                 app.edges_x = round(linspace(0,app.w,app.BoxesperlineEditField.Value+1));
%                 app.edges_y = round(linspace(0,app.h,app.BoxesperlineEditField.Value+1));
%                 set(app.BoxesperlineEditField,'Editable',false);
% 
%                 %initialize positions
%                 app.lower_pos = nan(app.BoxesperlineEditField.Value, app.BoxesperlineEditField.Value);
%                 app.upper_pos = nan(app.BoxesperlineEditField.Value, app.BoxesperlineEditField.Value);
% 
%                 %start loop
%                 app.curBox = 0;
%                 app.chat_click_loop();
            catch ME
                fprintf('Chat image load error: %s\n', ME.message);
            end

           try
                im = int32(zeros(app.h, app.w, app.Nframes));

                for i=1:app.Nframes
                    im(:,:,i) = imread(fname_cell, i);
                end
                app.dend_proj_im = squeeze(max(im,[],3));
%                 hold(app.projAxes,'off');
%                 imagesc(app.projAxes,app.dend_proj_im);
%                 axis(app.projAxes,'tight');
%                 hold(app.projAxes,'on');
            catch ME
                fprintf('Cell image load error: %s\n', ME.message);
            end

            set(app.UIFigure,'Name','Chat surface finder');

            app.init_surfaces();
            app.plot_surfaces();
            app.plot_cell_image();
            app.TogglesurfacesButton.Value = 0;
            app.TogglesurfacesButton.Text = 'Hide surfaces';
            cla(app.projAxes);

            app.segment();

            %axis(app.surfAxes, 'vis3d');
            %view(app.surfAxes, 90, 50);
        end
        
        function init_surfaces(app)
            app.surf_frame_lower = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.surf_frame_upper = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.surf_lower = ones(app.h, app.w) .* round(app.Nframes / 3);
            app.surf_upper = ones(app.h, app.w) .* round(app.Nframes * 2/3);    
            app.selection_type = cell(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.box_patches = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
        end
        
        function plot_surfaces(app)
            set(app.UIFigure, 'Name', 'Plotting surfaces ...');  
            %drawing
            [x,y] = meshgrid(1:app.w, 1:app.h);
%             x = x * app.xy_res;
%             y = y * app.xy_res;
            
            app.surf_lower_obj = surf(app.surfAxes,x,y,app.surf_lower,'edgecolor','none','FaceAlpha',0.5,'HitTest','off');
            hold(app.surfAxes, 'on');
            app.surf_upper_obj = surf(app.surfAxes,x,y,app.surf_upper,'edgecolor','none','FaceAlpha',0.5,'HitTest','off');
            
%             dend_color = imresize(app.dend_proj_im', [app.h, app.w]);
            
            hold(app.surfAxes, 'off');
            xlim(app.surfAxes,[1, app.w]);
            ylim(app.surfAxes,[1, app.h]);

            set(app.UIFigure,'Name','Chat surface finder');         
        end

        function plot_cell_image(app)
            [x,y] = meshgrid(1:app.w, 1:app.h);

            cmin = min(min(app.surf_lower,[],'all'), min(app.surf_upper,[],'all'));
            cmax = max(max(app.surf_lower,[],'all'), max(app.surf_upper,[],'all'));

            dend_color = double(app.dend_proj_im);  
            dend_color = dend_color - median(dend_color,'all');
            dend_color(dend_color <= 0) = nan;
            dend_color(~isnan(dend_color)) = 0;

            %dend_color = dend_color ./ max(dend_color,[],'all') * (cmax-cmin) + cmin;
            %dend_color = dend_color ./ max(dend_color,[],'all');


            hold(app.surfAxes, 'on');   
            p = surf(app.surfAxes,x, y, zeros(size(x)), dend_color,'edgecolor','none','HitTest','off','FaceAlpha',0.8);
            set(p,'alphadata',0);
            hold(app.surfAxes, 'off');     
            axis(app.surfAxes,'equal');
            app.surfAxes.DataAspectRatio = [1 1 .2];
            zlim(app.surfAxes,[0 app.Nframes]);         
            clim(app.surfAxes,[0, app.Nframes]);
        end
        
        function segment(app)
            %delete old rectangles and patches
            [r,c] = size(app.rect);
            for i=1:r
                for j=1:c
                    delete(app.rect(i,j));
                    if ishandle(app.box_patches(i,j)) && app.box_patches(i,j) > 0
                        try
                            delete(app.box_patches(i,j));
                        catch
                        end
                    end
                end
            end
            app.rect = [];
            app.selection_type = cell(app.RowsEditField.Value, app.ColumnsEditField.Value);
            app.box_patches = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);

            app.edges_rows = round(linspace(1,app.h,1+app.RowsEditField.Value));
            app.edges_cols = round(linspace(1,app.w,1+app.ColumnsEditField.Value));
            app.centers_rows = round((app.edges_rows(1:end-1) + app.edges_rows(2:end)) / 2);
            app.centers_cols = round((app.edges_cols(1:end-1) + app.edges_cols(2:end)) / 2);            

            hold(app.surfAxes,'on');
            for r=1:app.RowsEditField.Value
                for c=1:app.ColumnsEditField.Value                    
                    app.rect(r,c) = plot3(app.surfAxes, ...
                        [app.edges_cols(c), app.edges_cols(c+1), app.edges_cols(c+1), app.edges_cols(c),  app.edges_cols(c)], ...
                        [app.edges_rows(r), app.edges_rows(r),   app.edges_rows(r+1), app.edges_rows(r+1), app.edges_rows(r)], ...
                        [0 0 0 0 0], ....
                        'Color', 'k','LineWidth', 1,'HitTest','off');
                end
            end

            %regenerate frame surfaces if surface has been made
            if app.surface_generated
                app.surface_to_frames();
                app.frames_to_surface();
            else
                app.surf_frame_lower = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
                app.surf_frame_upper = zeros(app.RowsEditField.Value, app.ColumnsEditField.Value);
            end

            hold(app.surfAxes,'off');
            cla(app.projAxes);

        end
        
        function plot_selected_chat_projection(app)
            rangeRows = app.edges_rows(app.selected_row):app.edges_rows(app.selected_row+1);
            rangeCols = app.edges_cols(app.selected_col):app.edges_cols(app.selected_col+1);
            %step 1: set app.chat_im == 0 -> nan
            %step 2: calc f_trace as nanmean
            %app.f_trace = squeeze(mean(mean(app.chat_im(rangeRows,rangeCols,:),1),2));
            chim = double(app.chat_im(rangeRows,rangeCols,:));
            chim(chim == 0) = nan;
            app.f_trace = squeeze(nanmean(chim,[1,2]));
            plot(app.projAxes,app.f_trace,'k','LineWidth',2);      
            
            if app.surf_frame_lower(app.selected_row,app.selected_col)
                hold(app.projAxes,'on');
                x1 = app.surf_frame_lower(app.selected_row,app.selected_col);
                x2 = app.surf_frame_upper(app.selected_row,app.selected_col);
                scatter(app.projAxes,x1,app.f_trace(x1),'r','filled');
                scatter(app.projAxes,x2,app.f_trace(x2),'r','filled');
                hold(app.projAxes,'off');
            end
            
        end
        
        function select_peaks(app)
            app.selectDataPoints(app.projAxes, 1)
            app.selectDataPoints(app.projAxes, 2)            
        end

        function selectDataPoints(app, ax, roi_number)
            roi = drawpoint(ax);
            roi.Position(1) = round(roi.Position(1));
            roi.Position(2) = app.f_trace(roi.Position(1));
            if roi_number == 1
                app.roi_1 = roi;
            elseif roi_number == 2
                app.roi_2 = roi;
            end
        end


        function auto_detect_peaks(app,row,col)
            app.UIFigure.Name = 'Finding peaks...';
            x1 = app.surf_frame_lower(app.selected_row,app.selected_col);
            x2 = app.surf_frame_upper(app.selected_row,app.selected_col);
            x_range = [x1 - app.PeakshiftEditField.Value, x2 + app.PeakshiftEditField.Value];
            if x_range(1)<1
                x_range(1) = 1;
            end
            if x_range(end) > app.Nframes
                x_range(end) = app.Nframes;
            end
            for r=row-1:row+1
                for c=col-1:col+1
                    if r > 0 && r <= app.RowsEditField.Value && ...
                            c > 0 && c <= app.RowsEditField.Value && ...
                            ~(r == row && c == col)
                        app.peak_finder(r,c, x_range);
                    end
                end
            end
            app.UIFigure.Name = 'Chat surface finder';
        end
        
        function peak_finder(app,row,col,x_range)
            rangeRows = app.edges_rows(row):app.edges_rows(row+1);
            rangeCols = app.edges_cols(col):app.edges_cols(col+1);
            f = squeeze(mean(mean(app.chat_im(rangeRows,rangeCols,:),1),2));
            f = f(x_range(1):x_range(2));
            f_range = range(f);

            [~, peaks_loc] = findpeaks(f, ...
                    'NPeaks', 2, ...
                    'MinPeakDistance', 3, ...
                    'MinPeakProminence', f_range/3);
            
            if length(peaks_loc) == 2
                peaks_loc = sort(peaks_loc,'ascend');
                app.surf_frame_lower(row,col) = x_range(1)+peaks_loc(1)-1;
                app.surf_frame_upper(row,col) = x_range(1)+peaks_loc(2)-1;
                app.selection_type{row,col} = 'auto';
            end
            app.color_boxes();
        end
        
        function color_boxes(app)
            for r=1:app.RowsEditField.Value
                for c=1:app.ColumnsEditField.Value
                    if ~isempty(app.selection_type{r,c})
                       if ishandle(app.box_patches(r,c)) &&  app.box_patches(r,c) > 0
                           delete(app.box_patches(r,c))
                       end
                        x = get(app.rect(r,c),'Xdata');
                        y = get(app.rect(r,c),'Ydata');
                        z = get(app.rect(r,c),'Zdata');
                        switch app.selection_type{r,c}
                            case 'manual'                                
                                app.box_patches(r,c) = patch(app.surfAxes,x,y,z,[0.47,0.67,0.19],'FaceAlpha',0.4,'hittest','off');
                            case 'auto'
                                app.box_patches(r,c) = patch(app.surfAxes,x,y,z,[0.00,0.45,0.74],'FaceAlpha',0.4,'hittest','off');
                            case 'skip'
                                app.box_patches(r,c) = patch(app.surfAxes,x,y,z,[0.50,0.50,0.50],'FaceAlpha',0.4,'hittest','off');
                        end
                        %disp('made one');
                    end
                end
            end

            %bring selection back to front
            
        end
        
        function frames_to_surface(app)
             surf_frame_lower = app.surf_frame_lower;
             surf_frame_upper = app.surf_frame_upper;

             surf_frame_lower(surf_frame_lower==0) = nan;
             surf_frame_upper(surf_frame_upper==0) = nan;

             surf_frame_lower = inpaint_nans(surf_frame_lower);
             surf_frame_upper = inpaint_nans(surf_frame_upper);

            [Y, X] = meshgrid(app.centers_cols, app.centers_rows);
            [Ysurf, Xsurf] = meshgrid(1:app.w, 1:app.h);
            
            F = griddedInterpolant(X, Y, surf_frame_lower,'spline','linear');
            app.surf_lower = F(Xsurf, Ysurf);
            F = griddedInterpolant(X, Y, surf_frame_upper,'spline','linear');
            app.surf_upper = F(Xsurf, Ysurf);
        end


        function surface_to_frames(app)
            [Y, X] = meshgrid(app.centers_cols, app.centers_rows);
            [Ysurf, Xsurf] = meshgrid(1:app.w, 1:app.h);
            
            F = griddedInterpolant(Xsurf, Ysurf, app.surf_lower,'spline','linear');            
            app.surf_frame_lower = round(F(X, Y));
            F = griddedInterpolant(Xsurf, Ysurf, app.surf_upper,'spline','linear');            
            app.surf_frame_upper = round(F(X, Y));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, folder_name)
            if nargin==2
                app.load_folder(folder_name);
            end
        end

        % Button pushed function: LoadimagesfromfolderButton
        function LoadimagesfromfolderButtonPushed(app, event)
            app.load_folder();
        end

        % Button pushed function: TopdownviewButton
        function TopdownviewButtonPushed(app, event)
            view(app.surfAxes,2);
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            app.segment();
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            app.segment();
        end

        % Value changed function: TogglesurfacesButton
        function TogglesurfacesButtonValueChanged(app, event)
            value = app.TogglesurfacesButton.Value;
            if value
                app.TogglesurfacesButton.Text = 'Show surfaces';
                app.surf_lower_obj.Visible = false;
                app.surf_upper_obj.Visible = false;
            else
                app.TogglesurfacesButton.Text = 'Hide surfaces';
                app.surf_lower_obj.Visible = true;
                app.surf_upper_obj.Visible = true;
            end
        end

        % Button pushed function: SelectboxButton
        function SelectboxButtonPushed(app, event)
            view(app.surfAxes,2);
            app.selection_mode = true;
        end

        % Button down function: surfAxes
        function surfAxesButtonDown(app, event)
            if app.selection_mode          
                %reset old selection back to black
                if app.selected_row
                     r = app.rect(app.selected_row,app.selected_col);
                     set(r,'Color','k');
                    set(r,'linewidth', 1);
                end
                x = event.IntersectionPoint(1);
                y = event.IntersectionPoint(2);
                [~, app.selected_col] = min(abs(app.centers_cols - x));
                [~, app.selected_row] = min(abs(app.centers_rows - y));
                r = app.rect(app.selected_row,app.selected_col);
                set(r,'Color','r');
                set(r,'linewidth', 4);
                %bring to front
                ind = find(app.surfAxes.Children == r);
                app.surfAxes.Children = app.surfAxes.Children([setdiff(1:length(app.surfAxes.Children), ind), ind]);
                app.selection_mode = false;
                app.plot_selected_chat_projection();
            end
        end

        % Button pushed function: SelectpeaksmanuallyButton
        function SelectpeaksmanuallyButtonPushed(app, event)
            app.select_peaks();
        end

        % Button pushed function: ReselectButton
        function ReselectButtonPushed(app, event)
            delete(app.roi_1);
            delete(app.roi_2);
            app.select_peaks();
        end

        % Button pushed function: AcceptpeaksButton
        function AcceptpeaksButtonPushed(app, event)
            pos = sort([app.roi_1.Position(1), app.roi_2.Position(1)], 'ascend');
            app.surf_frame_lower(app.selected_row,app.selected_col) = pos(1);
            app.surf_frame_upper(app.selected_row,app.selected_col) = pos(2);
            delete(app.roi_1);
            delete(app.roi_2);
            app.selection_type{app.selected_row,app.selected_col} = 'manual';
            app.plot_selected_chat_projection();
            app.color_boxes();
        end

        % Button pushed function: AutodetectaroundselectionButton
        function AutodetectaroundselectionButtonPushed(app, event)
            app.auto_detect_peaks(app.selected_row,app.selected_col);
        end

        % Button pushed function: SkipboxButton
        function SkipboxButtonPushed(app, event)
            app.surf_frame_lower(app.selected_row,app.selected_col) = 0;
            app.surf_frame_upper(app.selected_row,app.selected_col) = 0;
            app.selection_type{app.selected_row,app.selected_col} = 'skip';
            app.plot_selected_chat_projection();
            app.color_boxes();
        end

        % Button pushed function: ComputesurfacesButton
        function ComputesurfacesButtonPushed(app, event)
            app.frames_to_surface();   
            app.TogglesurfacesButton.Value = 0;
            app.TogglesurfacesButton.Text = 'Hide surfaces';
            hold(app.surfAxes,'on');
            app.plot_surfaces();
            hold(app.surfAxes,'off');
            app.surface_generated = true;
        end

        % Button pushed function: SavesurfacesButton
        function SavesurfacesButtonPushed(app, event)
            [fname, fpath] = uiputfile('*.mat','Save surfaces file','chat_surfaces.mat');
            if fname
                appdata.surfaces = {'chat_ON', 'chat_OFF'};
                appdata.surfaceMaps{1} = app.surf_lower';
                appdata.surfaceMaps{2} = app.surf_upper';
                save([fpath filesep fname], 'appdata');
            end
        end

        % Value changed function: ZresEditField
        function ZresEditFieldValueChanged(app, event)
            value = app.ZresEditField.Value;
            app.z_res = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.projAxes = uiaxes(app.UIFigure);
            title(app.projAxes, 'ChAT channel projection')
            xlabel(app.projAxes, 'Z frame')
            ylabel(app.projAxes, 'Fluorescence (norm.)')
            zlabel(app.projAxes, 'Z')
            app.surfAxes = uiaxes(app.UIFigure);
            title(app.surfAxes, 'Cell and Surfaces')
            xlabel(app.surfAxes, 'X(pixels)')
            ylabel(app.surfAxes, 'Y (pixels)')
            zlabel(app.surfAxes, 'Z (frames)')
            app.surfAxes.ButtonDownFcn = createCallbackFcn(app, @surfAxesButtonDown, true);
            app.RowsEditFieldLabel = uilabel(app.UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowsEditField.Limits = [1 64];
            app.RowsEditField.RoundFractionalValues = 'on';
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Value = 8;
            app.ColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnsEditField.Limits = [1 64];
            app.ColumnsEditField.RoundFractionalValues = 'on';
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Value = 8;
            app.SmoothingwindowframesEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothingwindowframesEditFieldLabel.Text = 'Smoothing window (frames)';
            app.SmoothingwindowframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothingwindowframesEditField.Limits = [1 15];
            app.SmoothingwindowframesEditField.RoundFractionalValues = 'on';
            app.SmoothingwindowframesEditField.Editable = 'off';
            app.SmoothingwindowframesEditField.Value = 3;
            app.TopdownviewButton = uibutton(app.UIFigure, 'push');
            app.TopdownviewButton.ButtonPushedFcn = createCallbackFcn(app, @TopdownviewButtonPushed, true);
            app.TopdownviewButton.Text = 'Top down view';
            app.LoadimagesfromfolderButton = uibutton(app.UIFigure, 'push');
            app.LoadimagesfromfolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesfromfolderButtonPushed, true);
            app.LoadimagesfromfolderButton.Text = 'Load images from folder';
            app.XYresmpixEditFieldLabel = uilabel(app.UIFigure);
            app.XYresmpixEditFieldLabel.Text = 'XY res. (µm/pix)';
            app.XYresEditField = uieditfield(app.UIFigure, 'numeric');
            app.XYresEditField.Editable = 'off';
            app.ZresmframeEditFieldLabel = uilabel(app.UIFigure);
            app.ZresmframeEditFieldLabel.Text = 'Z res. (µm/frame)';
            app.ZresEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZresEditField.ValueChangedFcn = createCallbackFcn(app, @ZresEditFieldValueChanged, true);
            app.SavesurfacesButton = uibutton(app.UIFigure, 'push');
            app.SavesurfacesButton.ButtonPushedFcn = createCallbackFcn(app, @SavesurfacesButtonPushed, true);
            app.SavesurfacesButton.Text = 'Save surfaces';
            app.LoadsurfacesButton = uibutton(app.UIFigure, 'push');
            app.LoadsurfacesButton.Text = 'Load surfaces';
            app.SelectboxButton = uibutton(app.UIFigure, 'push');
            app.SelectboxButton.ButtonPushedFcn = createCallbackFcn(app, @SelectboxButtonPushed, true);
            app.SelectboxButton.Text = 'Select box';
            app.ComputesurfacesButton = uibutton(app.UIFigure, 'push');
            app.ComputesurfacesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputesurfacesButtonPushed, true);
            app.ComputesurfacesButton.Text = 'Compute surfaces';
            app.SelectpeaksmanuallyButton = uibutton(app.UIFigure, 'push');
            app.SelectpeaksmanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpeaksmanuallyButtonPushed, true);
            app.SelectpeaksmanuallyButton.Text = 'Select peaks manually';
            app.SkipboxButton = uibutton(app.UIFigure, 'push');
            app.SkipboxButton.ButtonPushedFcn = createCallbackFcn(app, @SkipboxButtonPushed, true);
            app.SkipboxButton.Text = 'Skip box';
            app.ManuallyselectedLabel = uilabel(app.UIFigure);
            app.ManuallyselectedLabel.Text = 'Manually selected';
            app.AutoselectedLabel = uilabel(app.UIFigure);
            app.AutoselectedLabel.Text = 'Auto selected';
            app.SkippedinterpolatedLabel = uilabel(app.UIFigure);
            app.SkippedinterpolatedLabel.Text = 'Skipped (interpolated)';
            app.Up_Button = uibutton(app.UIFigure, 'push');
            app.Up_Button.Text = '↑';
            app.Down_Button = uibutton(app.UIFigure, 'push');
            app.Down_Button.Text = '↓';
            app.Left_Button = uibutton(app.UIFigure, 'push');
            app.Left_Button.Text = '←';
            app.Right_Button = uibutton(app.UIFigure, 'push');
            app.Right_Button.Text = '→';
            app.TogglesurfacesButton = uibutton(app.UIFigure, 'state');
            app.TogglesurfacesButton.ValueChangedFcn = createCallbackFcn(app, @TogglesurfacesButtonValueChanged, true);
            app.TogglesurfacesButton.Text = 'Hide surfaces';
            app.CurrentselectionLabel = uilabel(app.UIFigure);
            app.CurrentselectionLabel.Text = 'Current selection';
            app.ReselectButton = uibutton(app.UIFigure, 'push');
            app.ReselectButton.ButtonPushedFcn = createCallbackFcn(app, @ReselectButtonPushed, true);
            app.ReselectButton.Text = 'Re-select';
            app.AcceptpeaksButton = uibutton(app.UIFigure, 'push');
            app.AcceptpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptpeaksButtonPushed, true);
            app.AcceptpeaksButton.Text = 'Accept peaks';
            app.PeakshiftinadjacentboxesEditFieldLabel = uilabel(app.UIFigure);
            app.PeakshiftinadjacentboxesEditFieldLabel.Text = 'Peak shift in adjacent boxes';
            app.PeakshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakshiftEditField.Limits = [1 Inf];
            app.PeakshiftEditField.RoundFractionalValues = 'on';
            app.PeakshiftEditField.Value = 10;
            app.AutodetectaroundselectionButton = uibutton(app.UIFigure, 'push');
            app.AutodetectaroundselectionButton.ButtonPushedFcn = createCallbackFcn(app, @AutodetectaroundselectionButtonPushed, true);
            app.AutodetectaroundselectionButton.Text = 'Auto-detect around selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CHAT_surface_finder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end