classdef CHAT_surface_finder_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ToggleChATpredictionsButton  matlab.ui.control.Button
        LoadControlPointsButton      matlab.ui.control.Button
        ColumnsEditField             matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel        matlab.ui.control.Label
        RowsEditField                matlab.ui.control.NumericEditField
        RowsEditFieldLabel           matlab.ui.control.Label
        StripResEditField            matlab.ui.control.NumericEditField
        gridrespixLabel              matlab.ui.control.Label
        ZresEditField                matlab.ui.control.NumericEditField
        ZresmframeEditFieldLabel     matlab.ui.control.Label
        XYresEditField               matlab.ui.control.NumericEditField
        XYresmpixEditFieldLabel      matlab.ui.control.Label
        ClearlastOFFpointButton      matlab.ui.control.Button
        SelectXYregiontoclearButton  matlab.ui.control.Button
        ClearlastONpointButton       matlab.ui.control.Button
        SavesurfacesButton           matlab.ui.control.Button
        DrawOFFbandButton            matlab.ui.control.Button
        UpdatesurfacesButton         matlab.ui.control.Button
        DrawONbandButton             matlab.ui.control.Button
        LoadimagesfromfolderButton   matlab.ui.control.Button
        chat_surfs                   matlab.ui.control.UIAxes
        off_band                     matlab.ui.control.UIAxes
        on_band                      matlab.ui.control.UIAxes
        yz_proj                      matlab.ui.control.UIAxes
        xz_proj                      matlab.ui.control.UIAxes
        cell_proj                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Nframes
        w
        h
        xy_res
        z_res
        chat

        chat_img % Description
        dend_proj % Description
        
        on_band_scatter
        off_band_scatter

        selected_row
        selected_col
        
        xz_proj_img
        yz_proj_img
        xz_proj_lines
        yz_proj_lines

        xy_proj_lines

        on_band_img
        off_band_img

        drawing_band
        start_drawing = false% Description
        done_drawing = true
        draw_axes

        on_band_pts
        off_band_pts

        rois

        on_interpolant
        off_interpolant

        on_surf
        off_surf

        on_surf_plot
        off_surf_plot

        show_predictions = false
        on_xz_pred_line
        off_xz_pred_line
        on_yz_pred_line
        off_yz_pred_line

        onZ
        offZ

    end
    
    methods (Access = private)
        
        function load_folder(app, fname)
            if nargin < 2
                fname = [];
            end
            if isempty(fname)
                fpath = uigetdir('','Select folder with images');
            else
                fpath = fname;
            end
            focus(app.UIFigure);
            set(app.UIFigure,'Name','Loading images...');

            try
                C = strsplit(fpath,filesep);
                cellname = C{end};
                fname_chat = sprintf('%s%s%s_chat.tif', fpath, filesep, cellname);
                fname_cell = sprintf('%s%s%s_cell.tif', fpath, filesep, cellname);

                info = imfinfo(fname_chat);
                app.Nframes = length(info);
                app.w = info(1).Width;
                app.h = info(1).Height;
                app.xy_res = 1./info(1).XResolution;
                app.XYresEditField.Value = app.xy_res;           
                desc = info(1).ImageDescription;
                try
                   a = strsplit(desc,'spacing=');
                   app.z_res = str2double(strtok(a{2}));
                   app.ZresEditField.Value = app.z_res;                
                catch
                   disp('z spacing not found in file');
                end
                
                app.chat = zeros(app.h, app.w, app.Nframes,'int32');

                for i=1:app.Nframes
                    app.chat(:,:,i) = imread(fname_chat, i);
                end

                app.chat = double(app.chat);
                app.chat(app.chat == 0) = nan;

            catch ME
                fprintf('Error loading ChAT image: %s\n', ME.message);
            end

            try
                im = zeros(app.h, app.w, app.Nframes,'int32');

                for i=1:app.Nframes
                    im(:,:,i) = imread(fname_cell, i);
                end
                app.dend_proj = squeeze(max(im,[],3));
            catch ME
                fprintf('Cell image load error: %s\n', ME.message);
            end

            set(app.UIFigure,'Name','Chat surface finder');
            set(app.chat_img, 'cdata', app.dend_proj);

            app.on_band_pts = zeros(0,2);
            app.off_band_pts = zeros(0,2);


            set(app.on_band_scatter,'xdata',nan,'ydata',nan);
            set(app.off_band_scatter,'xdata',nan,'ydata',nan);

            app.selected_row = round(app.w/2);
            app.selected_col = round(app.h/2);

            app.on_interpolant = [];
            app.off_interpolant = [];

            app.on_surf = [];
            app.off_surf = [];

            set(app.on_band_img,'cdata',[]);
            set(app.off_band_img,'cdata',[]);

            set(app.on_xz_pred_line, 'xdata', 1:app.w, 'ydata', nan(app.w,1));
            set(app.on_yz_pred_line, 'xdata', nan(app.h,1), 'ydata', 1:app.h);
            set(app.off_xz_pred_line, 'xdata', 1:app.w, 'ydata', nan(app.w,1));
            set(app.off_yz_pred_line, 'xdata', nan(app.h,1),'ydata', 1:app.h);
            
            app.select_point();

        end

        function select_point(app)


            rangeRows = max(1,app.selected_row-floor(app.StripResEditField.Value/2)):min(app.h,app.selected_row+ceil(app.StripResEditField.Value/2)); 
            rangeCols = max(1,app.selected_col-floor(app.StripResEditField.Value/2)):min(app.w,app.selected_col+ceil(app.StripResEditField.Value/2)); 
            

            set(app.xy_proj_lines(3),'value',rangeRows(1));
            set(app.xy_proj_lines(4),'value',rangeRows(end));
            set(app.xy_proj_lines(1),'value',rangeCols(1));
            set(app.xy_proj_lines(2),'value',rangeCols(end));
            

            % app.f_trace = squeeze(nanmean(app.chat_im(rangeRows,rangeCols,:),[1,2]));
            % plot(app.projAxes,app.f_trace,'k','LineWidth',2);      
            

            % % show yz and xz projections
            im = squeeze(nanmean(app.chat(rangeRows,:,:),1))';
            set(app.xz_proj_img, 'cdata', im);
            clim(app.xz_proj,[prctile(im,2,'all'), prctile(im,98,'all')]);

            set(app.xz_proj_lines(1),'value',rangeCols(1));
            set(app.xz_proj_lines(2),'value',rangeCols(end));

            im = squeeze(nanmean(app.chat(:,rangeCols,:),2));
            set(app.yz_proj_img, 'cdata', im);
            clim(app.yz_proj,[prctile(im,2,'all'), prctile(im,98,'all')]);

            set(app.yz_proj_lines(1),'value',rangeRows(1));
            set(app.yz_proj_lines(2),'value',rangeRows(end));

            delete(app.rois);
            app.rois = [];

            if app.show_predictions
                app.draw_predictions();
            else %clear the lines...
                set(app.on_xz_pred_line, 'ydata', nan(app.w,1));
                set(app.on_yz_pred_line, 'xdata', nan(app.h,1));
                set(app.off_xz_pred_line, 'ydata', nan(app.w,1));
                set(app.off_yz_pred_line, 'xdata', nan(app.h,1));
            end

            % rangeRows = max(1,app.selected_row-30):min(app.w,app.selected_row+30);
            % rangeCols = max(1,app.selected_col-30):min(app.h,app.selected_col+30);
            % if ~isempty(app.on_interpolant)
            %     z = round(app.on_interpolant(app.selected_col, app.selected_row));
            % 
            %     set(app.on_band_img,'cdata', app.chat(rangeRows,rangeCols,z));
            % end
            % if ~isempty(app.off_interpolant)
            %     z = round(app.off_interpolant(app.selected_col, app.selected_row));
            %     set(app.off_band_img,'cdata', app.chat(rangeRows,rangeCols,z));
            % end
            
            set(app.UIFigure,'Name','Chat surface finder');
        end

        function draw(app)
            app.done_drawing = false;
            set(app.UIFigure,'Name','Start drawing ChAT band');
            if strcmp(app.drawing_band,"ON")
                color = 'g';
            else
                color = 'm';
            end
            roi = drawpolyline(app.draw_axes,'color',color,'InteractionsAllowed','none','edgealpha',.5);
            if strcmp(app.drawing_band,"ON")
                if startsWith(app.draw_axes.Title.String,"YZ Proj")
                    app.on_band_pts = vertcat(app.on_band_pts, [app.selected_col*ones(size(roi.Position,1),1), roi.Position(:,2),roi.Position(:,1)]);
                else
                    app.on_band_pts = vertcat(app.on_band_pts, [roi.Position(:,1), app.selected_row*ones(size(roi.Position,1),1), roi.Position(:,2)]);
                end
            else
                if startsWith(app.draw_axes.Title.String,"YZ Proj")
                    app.off_band_pts = vertcat(app.off_band_pts, [app.selected_col*ones(size(roi.Position,1),1), roi.Position(:,2),roi.Position(:,1)]);
                else
                    app.off_band_pts = vertcat(app.off_band_pts, [roi.Position(:,1), app.selected_row*ones(size(roi.Position,1),1), roi.Position(:,2)]);
                end
            end
            app.rois = vertcat(app.rois, roi);

            set(app.UIFigure,'Name','Chat surface finder');
            

            app.rescatter();
            app.done_drawing = true;

        end
        
        function redraw_surfs(app)
            grid = {linspace(1,app.h,1+app.RowsEditField.Value), linspace(1,app.w,1+app.ColumnsEditField.Value)};
            c = linspace(0,1,256);

            if ~isempty(app.on_interpolant)
                app.on_surf = app.on_interpolant(grid); 

                color = app.on_surf - min(app.on_surf,[],'all');
                color = round(color ./ max(color,[],'all') * 255) + 1;
                nc = isnan(app.on_surf);
                color(nc) = 1;
                color = c(color);
                color(nc) = nan;
                set(app.on_surf_plot, 'ydata', grid{1}, 'xdata', grid{2}, 'zdata', app.on_surf, 'cdata',cat(3,zeros(size(app.on_surf)), color, zeros(size(app.on_surf))));

            end
            if ~isempty(app.off_interpolant)
                app.off_surf = app.off_interpolant(grid);     

                color = app.off_surf - min(app.off_surf,[],'all');
                color = round(color ./ max(color,[],'all') * 255) + 1;
                nc = isnan(app.off_surf);
                color(nc) = 1;
                color = c(color);
                color(nc) = nan;
                set(app.off_surf_plot, 'ydata', grid{1}, 'xdata', grid{2}, 'zdata', app.off_surf, 'cdata',cat(3,color, zeros(size(app.off_surf)), color));     
            end  
            view(app.chat_surfs,30,30);            
            
        end
        
        function rescatter(app)
            if ~isempty(app.on_band_pts(:,3))
            
                norm_z = app.on_band_pts(:,3) - min(app.on_band_pts(:,3));
                norm_z = norm_z ./ max(norm_z);
                norm_z(isnan(norm_z)) = 0;
    
                set(app.on_band_scatter,'xdata',app.on_band_pts(:,1),'ydata',app.on_band_pts(:,2),'sizedata',norm_z*70 + 20);
            end

            if ~isempty(app.off_band_pts(:,3))
            
                norm_z = app.off_band_pts(:,3) - min(app.off_band_pts(:,3));
                norm_z = norm_z ./ max(norm_z);
                norm_z(isnan(norm_z)) = 0;
    
                set(app.off_band_scatter,'xdata',app.off_band_pts(:,1),'ydata',app.off_band_pts(:,2),'sizedata',norm_z*70 + 20);
            end
        end
        
        function draw_predictions(app)
            if ~isempty(app.on_interpolant)
                if isempty(app.onZ) %not sure this can happen?
                    app.UpdatesurfacesButtonPushed();
                    return
                end
                set(app.on_xz_pred_line, 'ydata', app.onZ(app.selected_row, :));
                set(app.on_yz_pred_line, 'xdata', app.onZ(:, app.selected_col));
            end
            if ~isempty(app.off_interpolant)
                if isempty(app.offZ) %not sure this can happen?
                    app.UpdatesurfacesButtonPushed();
                    return
                end
                set(app.off_xz_pred_line, 'ydata', app.offZ(app.selected_row, :));
                set(app.off_yz_pred_line, 'xdata', app.offZ(:, app.selected_col));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, folder_name)
            hold(app.cell_proj,'on');
            app.chat_img = imagesc(app.cell_proj,0,'hittest','off');
            % axis(app.cell_proj,'tight');
            axis(app.cell_proj,'image');
            axis(app.cell_proj,'ij');            
            colormap(app.cell_proj,'gray');


            app.cell_proj.Toolbar.Visible = 'off';
            app.xz_proj.Toolbar.Visible = 'off';
            app.yz_proj.Toolbar.Visible = 'off';
            app.on_band.Toolbar.Visible = 'off';
            app.off_band.Toolbar.Visible = 'off';
            % app.chat_surfs.Toolbar.Visible = 'off';

            app.on_band_scatter = scatter(app.cell_proj,nan,nan,[],'g','filled','markerfacealpha',0.5);
            app.off_band_scatter = scatter(app.cell_proj,nan,nan,[],'m','filled','markerfacealpha',0.5);
            
            app.on_band_img = imagesc(app.on_band, 0,'hittest','off');
            app.off_band_img = imagesc(app.off_band, 0,'hittest','off');
            app.xz_proj_img = imagesc(app.xz_proj, 0,'hittest','off');
            app.yz_proj_img = imagesc(app.yz_proj, 0,'hittest','off');

            hold(app.xz_proj,'on');
            hold(app.yz_proj,'on');

            app.on_xz_pred_line = line(app.xz_proj,nan,nan,'color',[0 .5 0], 'linewidth', 2, 'linestyle', ':');
            app.off_xz_pred_line = line(app.xz_proj,nan,nan,'color',[.5 0 .5], 'linewidth', 2, 'linestyle', ':');
            app.on_yz_pred_line = line(app.yz_proj,nan,nan,'color',[0 .5 0], 'linewidth', 2, 'linestyle', ':');
            app.off_yz_pred_line = line(app.yz_proj,nan,nan,'color',[.5 0 .5], 'linewidth', 2, 'linestyle', ':');


            % axis(app.on_band,'tight');
            % axis(app.off_band,'tight');
            axis(app.xz_proj,'tight');
            axis(app.yz_proj,'tight');

            % axis(app.xz_proj,'ij');
            % axis(app.yz_proj,'ij');

            axis(app.on_band,'image');
            axis(app.off_band,'image');
            
            colormap(app.on_band, 'gray');
            colormap(app.off_band, 'gray');
            colormap(app.xz_proj, 'gray');
            colormap(app.yz_proj, 'gray');    

            app.xz_proj_lines = xline(app.xz_proj,[nan,nan],'r','hittest','off');
            app.yz_proj_lines = yline(app.yz_proj,[nan,nan],'r','hittest','off');

            app.xy_proj_lines(1:2) = xline(app.cell_proj,[nan,nan],'r','hittest','off');
            app.xy_proj_lines(3:4) = yline(app.cell_proj,[nan,nan],'r','hittest','off');
            
            hold(app.chat_surfs,'on');
            app.on_surf_plot = surf(app.chat_surfs, linspace(0,1,1+app.ColumnsEditField.Value), linspace(0,1,1+app.RowsEditField.Value), nan(1+app.ColumnsEditField.Value, 1+app.RowsEditField.Value));
            app.off_surf_plot = surf(app.chat_surfs, linspace(0,1,1+app.ColumnsEditField.Value), linspace(0,1,1+app.RowsEditField.Value), nan(1+app.ColumnsEditField.Value, 1+app.RowsEditField.Value));
            
            set(app.on_surf_plot,'edgecolor','flat','facecolor','none');
            set(app.off_surf_plot,'edgecolor','flat','facecolor','none');
            set(app.chat_surfs,'zdir','reverse');
            axis(app.chat_surfs,'vis3d');
            app.chat_surfs.PlotBoxAspectRatio = [1,1,.4];
            

            drawnow;
            if nargin==2
                app.load_folder(folder_name);
            end
        end

        % Button pushed function: LoadimagesfromfolderButton
        function LoadimagesfromfolderButtonPushed(app, event)
            app.load_folder();
        end

        % Button pushed function: DrawONbandButton
        function DrawONbandButtonPushed(app, event)
            app.start_drawing = true;
            app.drawing_band = "ON";
            set(app.UIFigure,'Name','Select figure to draw ON band');
        end

        % Button pushed function: DrawOFFbandButton
        function DrawOFFbandButtonPushed(app, event)
            app.start_drawing = true;
            app.drawing_band = "OFF";
            set(app.UIFigure,'Name','Select figure to draw OFF band');
        end

        % Button down function: yz_proj
        function yz_projButtonDown(app, event)
            if app.start_drawing
                app.start_drawing = false;
                app.draw_axes = app.yz_proj;
                app.draw()
            else
                app.selected_row = round(event.IntersectionPoint(2));
                app.select_point();                
            end
        end

        % Button down function: xz_proj
        function xz_projButtonDown(app, event)
            if app.start_drawing
                app.start_drawing = false;
                app.draw_axes = app.xz_proj;
                app.draw()
            else
                app.selected_col = round(event.IntersectionPoint(1));
                app.select_point();
            end
        end

        % Button down function: cell_proj, off_band, on_band
        function cell_projButtonDown(app, event)
            if app.done_drawing
                app.start_drawing = false;
                app.selected_col = round(event.IntersectionPoint(1));
                app.selected_row = round(event.IntersectionPoint(2));
                app.select_point();
            end
        end

        % Button pushed function: UpdatesurfacesButton
        function UpdatesurfacesButtonPushed(app, event)
            [X,Y] = meshgrid(1:app.w, 1:app.h);
            
            if ~isempty(app.on_band_pts)
                try
                    app.on_interpolant = scatteredInterpolant(app.on_band_pts(:,1:2),app.on_band_pts(:,3), 'natural', 'none');
                    app.onZ = app.on_interpolant(X,Y);
                    nz = isnan(app.onZ);
                    app.onZ(nz) = 1;
                    ind = sub2ind(size(app.chat), Y,X,round(app.onZ));
                    app.onZ(nz) = nan;
                    im = app.chat(ind);
                    im(nz) = nan;
                    set(app.on_band_img,'cdata', im);
                    clim(app.on_band,prctile(im,[2,98],'all'));
                end
            end
            if ~isempty(app.off_band_pts)
                try
                    app.off_interpolant = scatteredInterpolant(app.off_band_pts(:,1:2),app.off_band_pts(:,3), 'natural', 'none');
                    app.offZ = app.off_interpolant(X,Y);
                    nz = isnan(app.offZ);
                    app.offZ(nz) = 1;
                    ind = sub2ind(size(app.chat), Y,X,round(app.offZ));
                    app.offZ(nz) = nan;
                    im = app.chat(ind);
                    im(nz) = nan;
                    set(app.off_band_img,'cdata', im);
                    clim(app.off_band,prctile(im,[2,98],'all'));
                end
            end
            app.redraw_surfs();

            if app.show_predictions
                app.draw_predictions();
            end
        end

        % Value changed function: ColumnsEditField, RowsEditField
        function rowcolValueChanged(app, event)
            app.redraw_surfs();            
        end

        % Button pushed function: ClearlastONpointButton
        function ClearlastONpointButtonPushed(app, event)
            if ~isempty(app.on_band_pts)
                app.on_band_pts(end,:) = [];
                app.rescatter();
            end
        end

        % Button pushed function: ClearlastOFFpointButton
        function ClearlastOFFpointButtonPushed(app, event)
            if ~isempty(app.off_band_pts)
                app.off_band_pts(end,:) = [];
                app.rescatter();
            end
        end

        % Button pushed function: SelectXYregiontoclearButton
        function SelectXYregiontoclearButtonPushed(app, event)
            roi = drawpolygon(app.cell_proj,'color','r');
            
            if ~isempty(app.on_band_pts)
                inroi = roi.inROI(app.on_band_pts(:,1), app.on_band_pts(:,2));
            else
                inroi = [];
            end
            if ~isempty(inroi)
                app.on_band_pts(inroi,:) = [];
            end

            if ~isempty(app.off_band_pts)
                inroi = roi.inROI(app.off_band_pts(:,1), app.off_band_pts(:,2));
            else
                inroi = [];
            end
            if ~isempty(inroi)
                app.off_band_pts(inroi,:) = [];
            end

            delete(roi);
            app.rescatter();
           
        end

        % Button pushed function: SavesurfacesButton
        function SavesurfacesButtonPushed(app, event)
            [fname, fpath] = uiputfile('*.mat','Save surfaces file','chat_surfaces.mat');
            focus(app.UIFigure);
            
            if fname
                [X,Y] = meshgrid(1:app.w, 1:app.h);
                
                appdata.surfaces = {};
                appdata.surfaceMaps = {};
                appdata.controlPoints = {};
                if ~isempty(app.on_band_pts)
                    app.on_interpolant = scatteredInterpolant(app.on_band_pts(:,1:2),app.on_band_pts(:,3), 'natural', 'linear');
                    
                    appdata.surfaces{1} = 'chat_ON';
                    appdata.surfaceMaps{1} = app.on_interpolant(X,Y)';   
                    appdata.controlPoints{1} = app.on_band_pts; 

                end
                if ~isempty(app.off_band_pts)
                    app.off_interpolant = scatteredInterpolant(app.off_band_pts(:,1:2),app.off_band_pts(:,3), 'natural', 'linear');
                    Z = app.off_interpolant(X,Y);


                    appdata.surfaces{end+1} = 'chat_OFF';
                    appdata.surfaceMaps{end+1} = app.off_interpolant(X,Y)';   
                    appdata.controlPoints{end+1} = app.off_band_pts; 
                end
                save([fpath filesep fname], 'appdata');
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case '1'
                    app.DrawONbandButtonPushed();
                case '2'
                    app.DrawOFFbandButtonPushed();
                case 'e'
                    app.xz_projButtonDown();
                case '3'
                    app.yz_projButtonDown();
                case 'u'
                    app.UpdatesurfacesButtonPushed();
                case 'l'
                    app.LoadimagesfromfolderButtonPushed();
                case 's'
                    app.SavesurfacesButtonPushed();
                case '9'
                    app.ClearlastONpointButtonPushed();
                case '0'
                    app.ClearlastOFFpointButtonPushed();
                case 'c'
                    app.SelectXYregiontoclearButtonPushed();
                case 'k'
                    app.LoadControlPointsButtonPushed();
                case 't'
                    app.ToggleChATpredictionsButtonPushed();
            end
        end

        % Button pushed function: LoadControlPointsButton
        function LoadControlPointsButtonPushed(app, event)
            
            [fname, fpath] = uigetfile('.mat','Select surfaces file');
            focus(app.UIFigure);
            
            if fname
                appdata = load([fpath filesep fname]).appdata;
                if isfield(appdata, 'controlPoints')
                    for s=1:numel(appdata.surfaces)
                        switch appdata.surfaces{s}
                            case 'chat_ON'
                                app.on_band_pts = appdata.controlPoints{s};
                            case 'chat_OFF'
                                app.off_band_pts = appdata.controlPoints{s};                                
                        end
                    end
                    app.UpdatesurfacesButtonPushed();
                    app.rescatter();
                end
            end

        end

        % Button pushed function: ToggleChATpredictionsButton
        function ToggleChATpredictionsButtonPushed(app, event)
            app.show_predictions = ~app.show_predictions;
            app.select_point();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.cell_proj = uiaxes(app.UIFigure);
            title(app.cell_proj, 'Cell projection')
            xlabel(app.cell_proj, 'X')
            ylabel(app.cell_proj, 'Y')
            zlabel(app.cell_proj, 'Z')
            app.cell_proj.XLim = [0 800];
            app.cell_proj.YLim = [0 800];
            app.cell_proj.XTick = [];
            app.cell_proj.XTickLabel = '';
            app.cell_proj.YTick = [];
            app.cell_proj.YTickLabel = '';
            app.cell_proj.ZTick = [];
            app.cell_proj.ButtonDownFcn = createCallbackFcn(app, @cell_projButtonDown, true);
            app.xz_proj = uiaxes(app.UIFigure);
            title(app.xz_proj, 'XZ Proj (E)')
            xlabel(app.xz_proj, 'X')
            ylabel(app.xz_proj, 'Z')
            app.xz_proj.XTick = [];
            app.xz_proj.XTickLabel = '';
            app.xz_proj.YTick = [];
            app.xz_proj.ZTick = [];
            app.xz_proj.ButtonDownFcn = createCallbackFcn(app, @xz_projButtonDown, true);
            app.yz_proj = uiaxes(app.UIFigure);
            title(app.yz_proj, 'YZ Proj (3)')
            xlabel(app.yz_proj, 'Z')
            ylabel(app.yz_proj, 'Y')
            app.yz_proj.XLim = [0 1];
            app.yz_proj.YLim = [0 899];
            app.yz_proj.XTick = [];
            app.yz_proj.XTickLabel = '';
            app.yz_proj.YTick = [];
            app.yz_proj.YTickLabel = '';
            app.yz_proj.ZTick = [];
            app.yz_proj.ButtonDownFcn = createCallbackFcn(app, @yz_projButtonDown, true);
            app.on_band = uiaxes(app.UIFigure);
            title(app.on_band, 'ON band')
            app.on_band.XTick = [];
            app.on_band.YTick = [];
            app.on_band.ButtonDownFcn = createCallbackFcn(app, @cell_projButtonDown, true);
            app.off_band = uiaxes(app.UIFigure);
            title(app.off_band, 'OFF band')
            app.off_band.XTick = [];
            app.off_band.YTick = [];
            app.off_band.ButtonDownFcn = createCallbackFcn(app, @cell_projButtonDown, true);
            app.chat_surfs = uiaxes(app.UIFigure);
            title(app.chat_surfs, 'Chat surfaces')
            zlabel(app.chat_surfs, 'Z')
            app.chat_surfs.XTick = [];
            app.chat_surfs.YTick = [];
            app.LoadimagesfromfolderButton = uibutton(app.UIFigure, 'push');
            app.LoadimagesfromfolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesfromfolderButtonPushed, true);
            app.LoadimagesfromfolderButton.Text = 'Load images from folder (L)';
            app.DrawONbandButton = uibutton(app.UIFigure, 'push');
            app.DrawONbandButton.ButtonPushedFcn = createCallbackFcn(app, @DrawONbandButtonPushed, true);
            app.DrawONbandButton.Text = 'Draw ON band (1)';
            app.UpdatesurfacesButton = uibutton(app.UIFigure, 'push');
            app.UpdatesurfacesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatesurfacesButtonPushed, true);
            app.UpdatesurfacesButton.Text = 'Update surfaces (U)';
            app.DrawOFFbandButton = uibutton(app.UIFigure, 'push');
            app.DrawOFFbandButton.ButtonPushedFcn = createCallbackFcn(app, @DrawOFFbandButtonPushed, true);
            app.DrawOFFbandButton.Text = 'Draw OFF band (2)';
            app.SavesurfacesButton = uibutton(app.UIFigure, 'push');
            app.SavesurfacesButton.ButtonPushedFcn = createCallbackFcn(app, @SavesurfacesButtonPushed, true);
            app.SavesurfacesButton.Text = 'Save surfaces (S)';
            app.ClearlastONpointButton = uibutton(app.UIFigure, 'push');
            app.ClearlastONpointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlastONpointButtonPushed, true);
            app.ClearlastONpointButton.Text = 'Clear last ON point (9)';
            app.SelectXYregiontoclearButton = uibutton(app.UIFigure, 'push');
            app.SelectXYregiontoclearButton.ButtonPushedFcn = createCallbackFcn(app, @SelectXYregiontoclearButtonPushed, true);
            app.SelectXYregiontoclearButton.Text = 'Select XY region to clear (C)';
            app.ClearlastOFFpointButton = uibutton(app.UIFigure, 'push');
            app.ClearlastOFFpointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlastOFFpointButtonPushed, true);
            app.ClearlastOFFpointButton.Text = 'Clear last OFF point (0)';
            app.XYresmpixEditFieldLabel = uilabel(app.UIFigure);
            app.XYresmpixEditFieldLabel.Text = 'XY res. (µm/pix)';
            app.XYresEditField = uieditfield(app.UIFigure, 'numeric');
            app.XYresEditField.Editable = 'off';
            app.ZresmframeEditFieldLabel = uilabel(app.UIFigure);
            app.ZresmframeEditFieldLabel.Text = 'Z res. (µm/frame)';
            app.ZresEditField = uieditfield(app.UIFigure, 'numeric');
            app.gridrespixLabel = uilabel(app.UIFigure);
            app.gridrespixLabel.Text = 'Strip res. (pix)';
            app.StripResEditField = uieditfield(app.UIFigure, 'numeric');
            app.StripResEditField.Value = 20;
            app.RowsEditFieldLabel = uilabel(app.UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowsEditField.Limits = [1 64];
            app.RowsEditField.RoundFractionalValues = 'on';
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @rowcolValueChanged, true);
            app.RowsEditField.Value = 16;
            app.ColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnsEditField.Limits = [1 64];
            app.ColumnsEditField.RoundFractionalValues = 'on';
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @rowcolValueChanged, true);
            app.ColumnsEditField.Value = 16;
            app.LoadControlPointsButton = uibutton(app.UIFigure, 'push');
            app.LoadControlPointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadControlPointsButtonPushed, true);
            app.LoadControlPointsButton.Text = 'Load control points (K)';
            app.ToggleChATpredictionsButton = uibutton(app.UIFigure, 'push');
            app.ToggleChATpredictionsButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleChATpredictionsButtonPushed, true);
            app.ToggleChATpredictionsButton.Text = 'Toggle ChAT predictions (T)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CHAT_surface_finder_V2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end